classdef PID_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MN5552RoboticsandManufacturingAutomationLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        MotorConfigurationTab     matlab.ui.container.Tab
        DLabel                    matlab.ui.control.Label
        CLabel                    matlab.ui.control.Label
        BLabel                    matlab.ui.control.Label
        ALabel                    matlab.ui.control.Label
        UpdateShowMatricesButton  matlab.ui.control.Button
        KeEditField               matlab.ui.control.NumericEditField
        KeEditFieldLabel          matlab.ui.control.Label
        KtEditField               matlab.ui.control.NumericEditField
        KtEditFieldLabel          matlab.ui.control.Label
        REditField                matlab.ui.control.NumericEditField
        REditFieldLabel           matlab.ui.control.Label
        LEditField                matlab.ui.control.NumericEditField
        LEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        JEditField                matlab.ui.control.NumericEditField
        JEditFieldLabel           matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        PIDControllerTab          matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        RunPIDSimulationButton    matlab.ui.control.Button
        KdEditField               matlab.ui.control.NumericEditField
        KdEditFieldLabel          matlab.ui.control.Label
        KiEditField               matlab.ui.control.NumericEditField
        KiEditFieldLabel          matlab.ui.control.Label
        KpEditField               matlab.ui.control.NumericEditField
        KpEditFieldLabel          matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        StateFeedbackTab          matlab.ui.container.Tab
        FeedforwardGainKrLabel    matlab.ui.control.Label
        FeedbackGainKLabel        matlab.ui.control.Label
        DesignSimulateButton      matlab.ui.control.Button
        Pole2EditField            matlab.ui.control.NumericEditField
        Pole2EditFieldLabel       matlab.ui.control.Label
        Pole1EditField            matlab.ui.control.NumericEditField
        Pole1EditFieldLabel       matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description
        % --- [จุดที่ฟลุคต้องเพิ่ม] ตัวแปรสำหรับคำนวณคณิตศาสตร์ ---
        A, B, C, D                % State-Space Matrices
        sys_open                  % Open-loop model object [cite: 52]
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: JEditField
        function JEditFieldValueChanged(app, event)
            value = app.JEditField.Value;

        end

        % Button pushed function: UpdateShowMatricesButton
        function UpdateShowMatricesButtonPushed(app, event)
            % 1. ดึงค่าจากหน้าจอ
            J = app.JEditField.Value;   b = app.bEditField.Value;
            L = app.LEditField.Value;   R = app.REditField.Value;
            Kt = app.KtEditField.Value; Ke = app.KeEditField.Value;

            % 2. คำนวณ Matrices
            app.A = [-b/J, Kt/J; -Ke/L, -R/L];
            app.B = [0; 1/L];
            app.C = [1, 0]; app.D = 0;
            app.sys_open = ss(app.A, app.B, app.C, app.D);

            % 3. แสดงค่าบน Label
            app.ALabel.Text = sprintf('A = [%.2f, %.2f; %.2f, %.2f]', app.A(1,1), app.A(1,2), app.A(2,1), app.A(2,2));
            app.BLabel.Text = sprintf('B = [0; %.2f]', app.B(2));

            % 4. Plot กราฟ Open-loop
            [y, t] = step(app.sys_open, 5);
            plot(app.UIAxes, t, y, 'Color', '#002E5D', 'LineWidth', 2); % ใช้สีน้ำเงิน Brunel
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Open-loop Step Response (P2)');
        end

        % Button pushed function: RunPIDSimulationButton
        function RunPIDSimulationButtonPushed(app, event)
            G_plant = tf(app.sys_open);
            Kp = app.KpEditField.Value;
            Ki = app.KiEditField.Value;
            Kd = app.KdEditField.Value;

            % สร้างระบบเปรียบเทียบ
            sys_p = feedback(pid(Kp, 0, 0)*G_plant, 1);
            sys_pi = feedback(pid(Kp, Ki, 0)*G_plant, 1);
            sys_pid = feedback(pid(Kp, Ki, Kd)*G_plant, 1);

            % Plot ลง UIAxes2
            t = 0:0.01:5;
            hold(app.UIAxes2, 'off');
            plot(app.UIAxes2, t, step(sys_p, t), '--', 'LineWidth', 1); hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, t, step(sys_pi, t), '-.', 'LineWidth', 1);
            plot(app.UIAxes2, t, step(sys_pid, t), '-', 'LineWidth', 2);
            legend(app.UIAxes2, {'P', 'PI', 'PID'});
            grid(app.UIAxes2, 'on');

            % อัปเดตตารางสรุปผล
            app.UITable.Data = {'P-only', Kp, 'None', 'Offset'; ...
                'PI', Kp, Ki, 'Zero Error'; ...
                'PID', Kp, Ki, 'Optimized'};
        end

        % Button pushed function: DesignSimulateButton
        function DesignSimulateButtonPushed(app, event)
            % 1. Pole Placement (ใช้ EditField = Pole1, EditField2 = Pole2) [cite: 135-144]
            P = [app.Pole1EditField.Value, app.Pole2EditField.Value];
            K = place(app.A, app.B, P);

            % 2. Calculate Kr [cite: 142-143]
            Kr = -1 / (app.C * inv(app.A - app.B * K) * app.B);

            % 3. Simulate & Plot ลง UIAxes3 [cite: 153-162]
            sys_cl = ss(app.A - app.B * K, app.B * Kr, app.C, app.D);
            [y, t] = step(sys_cl, 3);
            plot(app.UIAxes3, t, y, 'm', 'LineWidth', 2);
            grid(app.UIAxes3, 'on');
            title(app.UIAxes3, 'State Feedback Response (P4)');

            % 4. แสดงผล Gain (Label = Gain K, Label2 = Kr)
            app.FeedbackGainKLabel.Text = sprintf('K = [%.1f, %.1f]', K(1), K(2));
            app.FeedforwardGainKrLabel.Text = sprintf('Kr = %.4f', Kr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorConfigurationTab = uitab(app.TabGroup);
            app.MotorConfigurationTab.Title = 'Motor Configuration';
            app.UIAxes = uiaxes(app.MotorConfigurationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.JEditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.JEditField.ValueChangedFcn = createCallbackFcn(app, @JEditFieldValueChanged, true);
            app.JEditField.Value = 0.0113;
            app.bEditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.bEditField.Value = 0.028;
            app.LEditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.LEditField.Value = 0.1;
            app.REditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.REditField.Value = 0.45;
            app.KtEditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.KtEditFieldLabel.Text = 'Kt';
            app.KtEditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.KtEditField.Value = 0.067;
            app.KeEditFieldLabel = uilabel(app.MotorConfigurationTab);
            app.KeEditFieldLabel.Text = 'Ke';
            app.KeEditField = uieditfield(app.MotorConfigurationTab, 'numeric');
            app.KeEditField.Value = 0.067;
            app.UpdateShowMatricesButton = uibutton(app.MotorConfigurationTab, 'push');
            app.UpdateShowMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateShowMatricesButtonPushed, true);
            app.UpdateShowMatricesButton.Text = 'Update & Show Matrices';
            app.ALabel = uilabel(app.MotorConfigurationTab);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.MotorConfigurationTab);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.MotorConfigurationTab);
            app.CLabel.Text = 'C';
            app.DLabel = uilabel(app.MotorConfigurationTab);
            app.DLabel.Text = 'D';
            app.PIDControllerTab = uitab(app.TabGroup);
            app.PIDControllerTab.Title = 'PID Controller';
            app.UIAxes2 = uiaxes(app.PIDControllerTab);
            title(app.UIAxes2, 'P vs PI vs PID')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.KpEditFieldLabel = uilabel(app.PIDControllerTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PIDControllerTab, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PIDControllerTab);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PIDControllerTab, 'numeric');
            app.KdEditFieldLabel = uilabel(app.PIDControllerTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.PIDControllerTab, 'numeric');
            app.RunPIDSimulationButton = uibutton(app.PIDControllerTab, 'push');
            app.RunPIDSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunPIDSimulationButtonPushed, true);
            app.RunPIDSimulationButton.Text = 'Run PID Simulation';
            app.UITable = uitable(app.PIDControllerTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.StateFeedbackTab = uitab(app.TabGroup);
            app.StateFeedbackTab.Title = 'State Feedback';
            app.UIAxes3 = uiaxes(app.StateFeedbackTab);
            title(app.UIAxes3, 'State Feedback Response')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Pole1EditFieldLabel = uilabel(app.StateFeedbackTab);
            app.Pole1EditFieldLabel.Text = 'Pole 1';
            app.Pole1EditField = uieditfield(app.StateFeedbackTab, 'numeric');
            app.Pole2EditFieldLabel = uilabel(app.StateFeedbackTab);
            app.Pole2EditFieldLabel.Text = 'Pole 2';
            app.Pole2EditField = uieditfield(app.StateFeedbackTab, 'numeric');
            app.DesignSimulateButton = uibutton(app.StateFeedbackTab, 'push');
            app.DesignSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @DesignSimulateButtonPushed, true);
            app.DesignSimulateButton.Text = 'Design & Simulate';
            app.FeedbackGainKLabel = uilabel(app.StateFeedbackTab);
            app.FeedbackGainKLabel.Text = 'Feedback Gain K';
            app.FeedforwardGainKrLabel = uilabel(app.StateFeedbackTab);
            app.FeedforwardGainKrLabel.Text = 'Feedforward Gain Kr';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'brunel.png';
            app.MN5552RoboticsandManufacturingAutomationLabel = uilabel(app.UIFigure);
            app.MN5552RoboticsandManufacturingAutomationLabel.Text = 'MN5552 Robotics and Manufacturing Automation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end